classdef FinalProjectDraft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RingButton_1      matlab.ui.control.Button
        PlayCardButton_1  matlab.ui.control.Button
        NewGameButton     matlab.ui.control.Button
        UIAxes_1          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        PlayCardButton_2  matlab.ui.control.Button
        PlayCardButton_3  matlab.ui.control.Button
        Player1Label      matlab.ui.control.Label
        Player2Label      matlab.ui.control.Label
        Player3Label      matlab.ui.control.Label
        HalliGalliLabel   matlab.ui.control.Label
        RingButton_2      matlab.ui.control.Button
        RingButton_3      matlab.ui.control.Button
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
        PlayedDeck_1 % player1's played deck of cards
        PlayedDeck_2 % player2's played deck of cards
        PlayedDeck_3 % player3's played deck of cards
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes_1,'off')
            axis(app.UIAxes_2,'off')
            axis(app.UIAxes_3,'off')
            
        end

        % Button pushed function: RingButton_1
        function ringBell_1(app, event)
            
        end

        % Button pushed function: NewGameButton
        function startGame(app, event)
% New Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % deal the cards
            cards = zeros(3, 20);
            app.player1 = cards(1,:);
            app.player2 = cards(2,:);
            app.player3 = cards(3,:);
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
            
            % Initialize the played decks
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
        end

        % Button pushed function: PlayCardButton_1
        function showCardImage_1(app, event)
                
            png = ".png";
            dash = "-";    

            % while player 1's turn == true
            cardVal = app.player1(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            
             % store card played from player1 into PlayedDeck_1
            app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
                
             % remove the drawn card from player 1's deck
            app.player1 = app.player1(2:end);
                
            end
        end

        % Button pushed function: PlayCardButton_2
        function showCardImage_2(app, event)
            
            png = ".png";
            dash = "-";   
            
            % while player 2's turn == true
            cardVal = app.player2(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
                
             % store card played from player2 into PlayedDeck_2
            app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
            
             % remove the drawn card from player 2's deck
            app.player2 = app.player2(2:end);
            
            end
        end

        % Button pushed function: PlayCardButton_3
        function showCardImage_3(app, event)
            
            png = ".png";
            dash = "-";   
            
            % while player 3's turn == true
            cardVal = app.player3(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
                
             % store card played from player3 into PlayedDeck_3
            app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
            
             % remove the drawn card from player 3's deck
            app.player3 = app.player3(2:end);
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RingButton_1 = uibutton(app.UIFigure, 'push');
            app.RingButton_1.ButtonPushedFcn = createCallbackFcn(app, @ringBell_1, true);
            app.RingButton_1.Icon = 'golden_bell.jpeg';
            app.RingButton_1.Text = 'Ring';
            app.PlayCardButton_1 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_1.ButtonPushedFcn = createCallbackFcn(app, @showCardImage_1, true);
            app.PlayCardButton_1.Text = 'Play Card';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @startGame, true);
            app.NewGameButton.Text = 'New Game';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.YTick = [0 0.5 1];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.YTick = [0 0.5 1];
            app.UIAxes_3.Visible = 'off';
            app.PlayCardButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_2.ButtonPushedFcn = createCallbackFcn(app, @showCardImage_2, true);
            app.PlayCardButton_2.Text = 'Play Card';
            app.PlayCardButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_3.ButtonPushedFcn = createCallbackFcn(app, @showCardImage_3, true);
            app.PlayCardButton_3.Text = 'Play Card';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.HalliGalliLabel = uilabel(app.UIFigure);
            app.HalliGalliLabel.Tooltip = {'Rules: Ring the bell once 5x of one type of fruit is shown in total. Ring the bell at the wrong time and the player gives one card to the other players. The player with the most cards at the end of the game wins!'};
            app.HalliGalliLabel.Text = 'Halli Galli';
            app.RingButton_2 = uibutton(app.UIFigure, 'push');
            app.RingButton_2.Icon = 'golden_bell.jpeg';
            app.RingButton_2.Text = 'Ring';
            app.RingButton_3 = uibutton(app.UIFigure, 'push');
            app.RingButton_3.Icon = 'golden_bell.jpeg';
            app.RingButton_3.Text = 'Ring';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end