classdef FinalProjectDraft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RingButton          matlab.ui.control.Button
        PlayCardButton      matlab.ui.control.Button
        StartGameButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        ShuffleCardsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes,'off')
            
        end

        % Button pushed function: RingButton
        function ringBell(app, event)
            
        end

        % Button pushed function: StartGameButton
        function startGame(app, event)
% Start Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % deal the cards
            cards = zeros(3, 20);
            app.player1 = cards(1,:);
            app.player2 = cards(2,:);
            app.player3 = cards(3,:);
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
        end

        % Button pushed function: PlayCardButton
        function showCardImage(app, event)
                
            png = ".png";
            dash = "-";    

            % while player 1's turn == true
            cardVal = app.player1(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes);
                ylim(app.UIAxes, [0, dim(1)]);
                xlim(app.UIAxes, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes);
                ylim(app.UIAxes, [0, dim(1)]);
                xlim(app.UIAxes, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes);
                ylim(app.UIAxes, [0, dim(1)]);
                xlim(app.UIAxes, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes);
                ylim(app.UIAxes, [0, dim(1)]);
                xlim(app.UIAxes, [0, dim(2)]);
            
             % remove the drawn card from player 1's deck
            app.player1 = app.player1(2:end);
                
            end
            
        end

        % Button pushed function: ShuffleCardsButton
        function shuffleCards(app, event)
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RingButton = uibutton(app.UIFigure, 'push');
            app.RingButton.ButtonPushedFcn = createCallbackFcn(app, @ringBell, true);
            app.RingButton.Text = 'Ring';
            app.PlayCardButton = uibutton(app.UIFigure, 'push');
            app.PlayCardButton.ButtonPushedFcn = createCallbackFcn(app, @showCardImage, true);
            app.PlayCardButton.Text = 'Play Card';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @startGame, true);
            app.StartGameButton.Text = 'Start Game';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [0 0.5 1];
            app.ShuffleCardsButton = uibutton(app.UIFigure, 'push');
            app.ShuffleCardsButton.ButtonPushedFcn = createCallbackFcn(app, @shuffleCards, true);
            app.ShuffleCardsButton.Text = 'Shuffle Cards';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end