classdef FinalProjectDraft_CurrentStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RingButton_1         matlab.ui.control.Button
        PlayCardButton_1     matlab.ui.control.Button
        NewGameButton        matlab.ui.control.Button
        PlayCardButton_2     matlab.ui.control.Button
        PlayCardButton_3     matlab.ui.control.Button
        Player1Label         matlab.ui.control.Label
        Player2Label         matlab.ui.control.Label
        Player3Label         matlab.ui.control.Label
        HalliGalli_Title     matlab.ui.control.Label
        RingButton_2         matlab.ui.control.Button
        RingButton_3         matlab.ui.control.Button
        Counter1             matlab.ui.control.Label
        Counter2             matlab.ui.control.Label
        Counter3             matlab.ui.control.Label
        TotalcardsLabel_1    matlab.ui.control.Label
        TotalcardsLabel_2    matlab.ui.control.Label
        TotalcardsLabel_3    matlab.ui.control.Label
        Counter1_storedDeck  matlab.ui.control.Label
        Counter2_storedDeck  matlab.ui.control.Label
        Counter3_storedDeck  matlab.ui.control.Label
        BannerLabel          matlab.ui.control.Label
        ExitGameButton       matlab.ui.control.Button
        UIAxes_1             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
        PlayedDeck_1 % player1's played deck of cards
        PlayedDeck_2 % player2's played deck of cards
        PlayedDeck_3 % player3's played deck of cards
    end
    
    methods (Access = private)
                
        function results = Counter(app,Counter,player)
            Counter.Text =  num2str(length(player));
        end
        
        function results = showCardImage1(app, event)
            png = ".png";
            dash = "-";    

            % while player 1's turn == true
            cardVal = app.player1(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);   
            end
        end
        
        function results = showCardImage2(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player2(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            end
        end
        
        function results = showCardImage3(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player3(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            end
        end
        
        function results = WinFun(app, event)
            % WinFun function to determine the player with the most cards
            % and display a winner banner over their cards
            
            % To be displayed over winning player
            app.BannerLabel.FontColor = [0.39,0.83,0.07]; % Color text green
            app.BannerLabel.FontSize = 30;
            app.BannerLabel.Text = "WINNER!!!";
        end
        
        function results = ClearImages(app)
            imshow([0, 0, 0], 'Parent', app.UIAxes_1);
            imshow([0, 0, 0], 'Parent', app.UIAxes_2);
            imshow([0, 0, 0], 'Parent', app.UIAxes_3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes_1,'off')
            axis(app.UIAxes_2,'off')
            axis(app.UIAxes_3,'off')
            
            % Starts New Game
            app.newGame()
        end

        % Button pushed function: NewGameButton
        function newGame(app, event)
% New Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % Initialize player's hands and deal the cards
            app.player1 = [];
            app.player2 = [];
            app.player3 = [];
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
            
            % Initialize the played decks (face up cards)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
            
            % Update total cards counter
            app.Counter1.Text = num2str(length(app.player1));
            app.Counter2.Text = num2str(length(app.player2));
            app.Counter3.Text = num2str(length(app.player3));
            app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
            app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
            app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
            
            % Removes cards being displayed
            app.ClearImages()
        end

        % Button pushed function: PlayCardButton_1
        function PlayCardButton_1Pushed(app, event)
            app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
            app.player1(1) = [];
            showCardImage1(app, event)
            app.Counter(app.Counter1,app.player1)
            app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
        end

        % Button pushed function: PlayCardButton_2
        function PlayCardButton_2Pushed(app, event)
            app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
            app.player2(1) = [];
            showCardImage2(app, event)
            app.Counter(app.Counter2,app.player2)
            app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
        end

        % Button pushed function: PlayCardButton_3
        function PlayCardButton_3Pushed(app, event)
            app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
            app.player3(1) = [];
            showCardImage3(app, event)
            app.Counter(app.Counter3,app.player3)
            app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
        end

        % Button pushed function: RingButton_1
        function ringBell_1(app, event)
            app.BannerLabel.Text = "Player 1 rang the bell!"
            
            s5f = Bellring(app,event) ;
             if s5f == 1
                 % player1 collects all revealed deck of cards
                 allrevealedcard = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3] ;
                 
                 % put the revealeddeck into the end of player1's deck
                 app.player1 = [ app.player1, allrevealedcard ] ;
                 
                 app.BannerLabel.Text = "You won the cards!" ;
             else
                 % penalize the player
                 % app.BannerLabel.Text = "Sorry, Wrong timing!" ;
                 app.player2 = [app.player2, app.player1(2)] ;
                 app.player3 = [app.player3, app.player1(3)] ;
                 app.BannerLabel.Text = "Player 1 gave out one card to each player" ;
             end
             
            function same5fruit = Bellring(app, event)
                % 'same5fruit' will return 1 if the player rang bell 
                % when the same fruit present
                fruit1 = floor(app.player1(1)/5) ;
                fruitcount1 = mod(app.player1(1), 5) ;
                fruit2 = floor(app.player2(1)/5) ;
                fruitcount2 = mod(app.player2(1), 5) ;
                fruit3 = floor(app.player3(1)/5) ;
                fruitcount3 = mod(app.player3(1), 5) ;
                same5fruit = 0 ;
                
                if (fruit1 == fruit2) && (fruitcount1+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit2 == fruit3) && (fruitcount3+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 == fruit3) && (fruitcount1+fruitcount3 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 ~= fruit2) && (fruit2 ~= fruit3) && (fruit1 ~= fruit3)
                    if fruit1 == 5
                        same5fruit = 1 ;
                    elseif fruit2 == 5
                        same5fruit = 1 ;
                    elseif fruit3 == 5
                        same5fruit = 1 ;
                    end
                end
            end
        end

        % Button pushed function: RingButton_2
        function ringBell_2(app, event)
            app.BannerLabel.Text = "Player 2 rang the bell!"
            
            s5f = Bellring(app,event) ;
             if s5f == 1
                 % player1 collects all revealed deck of cards
                 revealedplayer1 = app.PlayedDeck_1 ;
                 revealedplayer2 = app.PlayedDeck_2 ;
                 revealedplayer3 = app.PlayedDeck_3 ;
                 allrevealedcard = [revealedplayer1, revealedplayer2, revealedplayer3] ;
                 
                 % ramdomly shuffle revealed cards
                 n = length(allrevealedcard) ;
                 idx = randperm(n) ;
                 revealeddeck = allrevealedcard(idx) ;
                 
                 % put the revealeddeck into the end of player1's deck
                 app.player2 = [ app.player2, revealeddeck ] ;
                 
                 app.BannerLabel.Text = "You won the cards!" ;
             else
                 % penalize the player
                 % app.BannerLabel.Text = "Sorry, Wrong timing!" ;
                 app.player1 = [app.player1, app.player2(2)] ;
                 app.player3 = [app.player3, app.player2(3)] ;
                 app.BannerLabel.Text = "Player 2 gave out one card to each player" ;
             end
             
            function same5fruit = Bellring(app, event)
                % 'same5fruit' will return 1 if the player rang bell 
                % when the same fruit present
                fruit1 = floor(app.player1(1)/5) ;
                fruitcount1 = mod(app.player1(1), 5) ;
                fruit2 = floor(app.player2(1)/5) ;
                fruitcount2 = mod(app.player2(1), 5) ;
                fruit3 = floor(app.player3(1)/5) ;
                fruitcount3 = mod(app.player3(1), 5) ;
                same5fruit = 0 ;
                
                if (fruit1 == fruit2) && (fruitcount1+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit2 == fruit3) && (fruitcount3+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 == fruit3) && (fruitcount1+fruitcount3 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 ~= fruit2) && (fruit2 ~= fruit3) && (fruit1 ~= fruit3)
                    if fruit1 == 5
                        same5fruit = 1 ;
                    elseif fruit2 == 5
                        same5fruit = 1 ;
                    elseif fruit3 == 5
                        same5fruit = 1 ;
                    end
                end
            end
        end

        % Button pushed function: RingButton_3
        function ringBell_3(app, event)
            app.BannerLabel.Text = "Player 3 rang the bell!"
            
            s5f = Bellring(app,event) ;
             if s5f == 1
                 % player1 collects all revealed deck of cards
                 revealedplayer1 = app.PlayedDeck_1 ;
                 revealedplayer2 = app.PlayedDeck_2 ;
                 revealedplayer3 = app.PlayedDeck_3 ;
                 allrevealedcard = [revealedplayer1, revealedplayer2, revealedplayer3] ;
                 
                 % ramdomly shuffle revealed cards
                 n = length(allrevealedcard) ;
                 idx = randperm(n) ;
                 revealeddeck = allrevealedcard(idx) ;
                 
                 % put the revealeddeck into the end of player1's deck
                 app.player3 = [ app.player3, revealeddeck ] ;
                 
                 app.BannerLabel.Text = "You won the cards!" ;
             else
                 % penalize the player
                 % app.BannerLabel.Text = "Sorry, Wrong timing!" ;
                 app.player2 = [app.player2, app.player3(2)] ;
                 app.player1 = [app.player1, app.player3(3)] ;
                 app.BannerLabel.Text = "Player 3 gave out one card to each player" ;
             end
             
            function same5fruit = Bellring(app, event)
                % 'same5fruit' will return 1 if the player rang bell 
                % when the same fruit present
                fruit1 = floor(app.player1(1)/5) ;
                fruitcount1 = mod(app.player1(1), 5) ;
                fruit2 = floor(app.player2(1)/5) ;
                fruitcount2 = mod(app.player2(1), 5) ;
                fruit3 = floor(app.player3(1)/5) ;
                fruitcount3 = mod(app.player3(1), 5) ;
                same5fruit = 0 ;
                
                if (fruit1 == fruit2) && (fruitcount1+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit2 == fruit3) && (fruitcount3+fruitcount2 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 == fruit3) && (fruitcount1+fruitcount3 == 5)
                    same5fruit = 1 ;
                elseif (fruit1 ~= fruit2) && (fruit2 ~= fruit3) && (fruit1 ~= fruit3)
                    if fruit1 == 5
                        same5fruit = 1 ;
                    elseif fruit2 == 5
                        same5fruit = 1 ;
                    elseif fruit3 == 5
                        same5fruit = 1 ;
                    end
                end
            end
        end

        % Button pushed function: ExitGameButton
        function ExitGame(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RingButton_1 = uibutton(app.UIFigure, 'push');
            app.RingButton_1.ButtonPushedFcn = createCallbackFcn(app, @ringBell_1, true);
            app.RingButton_1.Icon = 'golden_bell.jpeg';
            app.RingButton_1.Text = 'Ring';
            app.PlayCardButton_1 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_1Pushed, true);
            app.PlayCardButton_1.Text = 'Play Card';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @newGame, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayCardButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_2Pushed, true);
            app.PlayCardButton_2.Text = 'Play Card';
            app.PlayCardButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayCardButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_3Pushed, true);
            app.PlayCardButton_3.Text = 'Play Card';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.HalliGalli_Title = uilabel(app.UIFigure);
            app.HalliGalli_Title.Tooltip = {'Rules: Ring the bell once 5x of one type of fruit is shown in total. Ring the bell at the wrong time and the player gives one card to the other players. The player with the most cards at the end of the game wins!'};
            app.HalliGalli_Title.Text = 'Halli Galli';
            app.RingButton_2 = uibutton(app.UIFigure, 'push');
            app.RingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ringBell_2, true);
            app.RingButton_2.Icon = 'golden_bell.jpeg';
            app.RingButton_2.Text = 'Ring';
            app.RingButton_3 = uibutton(app.UIFigure, 'push');
            app.RingButton_3.ButtonPushedFcn = createCallbackFcn(app, @ringBell_3, true);
            app.RingButton_3.Icon = 'golden_bell.jpeg';
            app.RingButton_3.Text = 'Ring';
            app.Counter1 = uilabel(app.UIFigure);
            app.Counter1.Text = '0';
            app.Counter2 = uilabel(app.UIFigure);
            app.Counter2.Text = '0';
            app.Counter3 = uilabel(app.UIFigure);
            app.Counter3.Text = '0';
            app.TotalcardsLabel_1 = uilabel(app.UIFigure);
            app.TotalcardsLabel_1.Text = 'Total cards:';
            app.TotalcardsLabel_2 = uilabel(app.UIFigure);
            app.TotalcardsLabel_2.Text = 'Total cards:';
            app.TotalcardsLabel_3 = uilabel(app.UIFigure);
            app.TotalcardsLabel_3.Text = 'Total cards:';
            app.Counter1_storedDeck = uilabel(app.UIFigure);
            app.Counter1_storedDeck.Text = '0';
            app.Counter2_storedDeck = uilabel(app.UIFigure);
            app.Counter2_storedDeck.Text = '0';
            app.Counter3_storedDeck = uilabel(app.UIFigure);
            app.Counter3_storedDeck.Text = '0';
            app.BannerLabel = uilabel(app.UIFigure);
            app.BannerLabel.Text = {''; ''};
            app.ExitGameButton = uibutton(app.UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGame, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_1.YTick = [0 0.5 1];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.YTick = [0 0.5 1];
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft_CurrentStatus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end