classdef FinalProjectDraft_Thingspeak3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardGame23UIFigure  matlab.ui.Figure
        UIAxes_1            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        RingButton_1        matlab.ui.control.Button
        PlayCardButton_1    matlab.ui.control.Button
        NewGameButton       matlab.ui.control.Button
        PlayCardButton_2    matlab.ui.control.Button
        PlayCardButton_3    matlab.ui.control.Button
        Player1Label        matlab.ui.control.Label
        Player2Label        matlab.ui.control.Label
        Player3Label        matlab.ui.control.Label
        HalliGalli_Title    matlab.ui.control.Label
        RingButton_2        matlab.ui.control.Button
        RingButton_3        matlab.ui.control.Button
        Counter1            matlab.ui.control.Label
        Counter2            matlab.ui.control.Label
        Counter3            matlab.ui.control.Label
        TotalcardsLabel_1   matlab.ui.control.Label
        TotalcardsLabel_2   matlab.ui.control.Label
        TotalcardsLabel_3   matlab.ui.control.Label
        BannerLabel         matlab.ui.control.Label
        ExitGameButton      matlab.ui.control.Button
        BannerLabel_2       matlab.ui.control.Label
        RulesButton         matlab.ui.control.Button
        RulesText           matlab.ui.control.TextArea
        ChoosewhichplayeryouwouldliketobeDropDownLabel  matlab.ui.control.Label
        PlayerChoice        matlab.ui.control.DropDown
        ConfirmButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
        PlayedDeck_1 % player1's played deck of cards
        PlayedDeck_2 % player2's played deck of cards
        PlayedDeck_3 % player3's played deck of cards
        playCardButton1 % Player 1 played a card (Boolean)
        playCardButton2 % Player 2 played a card (Boolean)
        playCardButton3 % Player 3 played a card (Boolean)
        player1Turn % Player 1's turn (Boolean)
        player2Turn % Player 2's turn (Boolean)
        player3Turn % Player 3's turn (Boolean)
    end
    
    properties (Access = public)
        channelID = 1250202
        writeAPIkey = "FLMRWKXF42OL7L1V"
        readAPIkey = "VDAO38SN46G4W16T" % Description
    end
    
    methods (Access = private)
                
        function results = Counter(app,Counter,player)
            Counter.Text =  num2str(length(player));
        end
        
        function card = showCardImage1(app, event)
            png = ".png";
            dash = "-";    

            % while player 1's turn == true
            cardVal = app.player1(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);   
            end
        end
        
        function card = showCardImage2(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player2(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            end
        end
        
        function card = showCardImage3(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player3(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            end
        end
        
        function results = ClearImages(app)
            imshow([0, 0, 0], 'Parent', app.UIAxes_1);
            imshow([0, 0, 0], 'Parent', app.UIAxes_2);
            imshow([0, 0, 0], 'Parent', app.UIAxes_3);
        end
        
        function fruit = DetermineFruit(app, player)
            cardVal = player(1);
            if (0 < cardVal) && (cardVal <= 5)
                fruit = "apple";
            elseif (5 < cardVal) && (cardVal <= 10)
                fruit = "strawberry";
            elseif (11 < cardVal) && (cardVal <= 15)
                fruit = "lime";
            else % cardVal is 16-20
                fruit = "banana";
            end
        end
        
        function value = fruitValue(app, player)
            value = mod(player,5)+1;
        end
        
        function fiveFruit = EvaluateCards(app, event)
            % 'fiveFruit' will return 1 if the player rang bell 
            % when the same fruit present
            
            % player1's faceup card
            if isempty(app.PlayedDeck_1)
                fruit1 = "";
                value1 = 0;
            else
                fruit1 = DetermineFruit(app, app.PlayedDeck_1(end));
                value1 = fruitValue(app, app.PlayedDeck_1(end));
            end

            % player2's faceup card
            if isempty(app.PlayedDeck_2)
                fruit2 = "";
                value2 = 0;
            else
                fruit2 = DetermineFruit(app, app.PlayedDeck_2(end));
                value2 = fruitValue(app, app.PlayedDeck_2(end));
            end
            
            % player3's faceup card
            if isempty(app.PlayedDeck_3)
                fruit3 = "";
                value3 = 0;
            else
                fruit3 = DetermineFruit(app, app.PlayedDeck_3(end));
                value3 = fruitValue(app, app.PlayedDeck_3(end));
            end
            
            % create 2-d array
            cardsDisplayed = [fruit1, fruit2, fruit3; value1, value2, value3];
            total1 = 0;
            total2 = 0;
            total3 = 0;
            
            for i = 1:3
                if cardsDisplayed(1,i) == fruit1
                    total1 = total1 + str2double(cardsDisplayed(2,i));
                elseif cardsDisplayed(1,i) == fruit2
                    total2 = total2 + str2double(cardsDisplayed(2,i));
                elseif cardsDisplayed(1,i) == fruit3
                    total3 = total3 + str2double(cardsDisplayed(2,i));
                end
            end
            
            % Initialize the fiveFruit logical array (1x1)
            fiveFruit = [];
                
            % Are the if statements below evaluating ALL options? 
            % (i.e.) fruit1 == fruit2 && fruit1 == fruit3 possibility?
            
            if total1 == 5
                fiveFruit = true;
            elseif total2 == 5
                fiveFruit = true;
            elseif total3 == 5
                fiveFruit = true;
            end
            
            % This works, but it counts wins for 5 fruits or more.
%             if str2num(total1) >= 5
%                 fiveFruit = 1;
%             elseif str2num(total2) >= 5
%                 fiveFruit = 1;
%             elseif str2num(total3) >= 5
%                 fiveFruit = 1;
%             end
               
%             if (fruit1 == fruit2) && (value1+value2 == 5)
%                  fiveFruit = 1; 
%             elseif (fruit2 == fruit3) && (value3+value2 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 == fruit3) && (value1+value3 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 == fruit2 && fruit1 == fruit3) && (value1+value2+value3 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 ~= fruit2) && (fruit2 ~= fruit3) && (fruit1 ~= fruit3)
%                 if value1 == 5
%                     fiveFruit = 1;
%                 elseif value2 == 5
%                     fiveFruit = 1;
%                 elseif value3 == 5
%                     fiveFruit = 1;
%                     end
%                 end
        end
        
        function results = ClearPlayedDecks(app, event)
            ClearImages(app)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
        end
        
        function results = Endgame(app, event)
            % WinFun function to execute when a player has 0 cards left 
            % in their hand, determine the player with the most cards
            % and display a winner banner over their UIAxes.
            numCards1 = length(app.player1);
            numCards2 = length(app.player2);
            numCards3 = length(app.player3);
            
            if (numCards1 == 0 || numCards2 == 0 || numCards3 == 0)
                app.BannerLabel_2.Text = "End of Game!";
                
%                 if (numCards1 > numCards2 && numCards2 > numCards3)
%                     % 1 > 2 > 3
%                     % then player1 has the most cards
%                     app.BannerLabel.Position = [-75,175,332,53];
%                 elseif (numCards1 > numCards2 && numCards1 > numCards3)
%                     % 1 > 3 > 2
%                         % then player1 has the most cards
%                         app.BannerLabel.Position = [-75,175,332,53];
%                 elseif (numCards1)
%                     % 2 > 1 > 3
%                     % 2 > 3 > 1
%                     % 3 > 2 > 1
%                         
%                     end
            end
            
%             % To be displayed over winning player
%             app.BannerLabel.FontColor = [0.39, 0.83, 0.07]; % Color text green
%             app.BannerLabel.FontSize = 30;
%             app.BannerLabel.Text = "WINNER!!!";
        end
        
    end
    
    methods (Access = public)
        
        function results = thingspeakRun(app, field, value)
            % We tried making this funciton so it's easier for us to WRITE
            % in thingspeak but we aren't sure if it works yet (It should)
            thingSpeakWrite(app.channelID, 'Fields', field, 'Values', value, 'WriteKey', app.writeAPIkey);
            wait(2)
            
        end


        function results = thingspeakGet(app, field)
            % We tried making this funciton so it's easier for us to READ
            % in thingspeak but we aren't sure if it works yet (It should)
            % Field is an array of the field numbers you want to get info
            % from
            thingSpeakRead(app.channelID, 'Fields', field);
            wait(2)
        end


        function fieldVal = getFieldValue(app)
            % Gets field value we want to send the data to, haven't used
            % this yet and also don't remember why we thought it was
            % necessary in the moment but it might be good to have
            if app.PlayerChoice.Value == "Player 1"
                fieldVal = 1;
            elseif app.PlayerChoice.Value == "Player 2"
                fieldVal = 2;
            elseif app.PlayerChoice.Value == "Player 3"
                fieldVal = 3;
            end
        end

        
        function results = playerTurn(app)
            % allows players to go based on who went before
            % Player 1 can go if player 3 went before them
            % Player 2 can go if player 1 went before them
            % Player 3 can go if player 2 went before them
            
            if app.playCardButton1 == true
                app.player2Turn = true;
                app.playCardButton1 = false;
                set(app.PlayCardButton_1, 'Enable', 'on') ;
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
            elseif app.playCardButton2 == true
                app.player3Turn = true;
                app.playCardButton2 = false;
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_2, 'Enable', 'on') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
            elseif app.playCardButton3 == true
                app.player1Turn = true;
                app.playCardButton3 = false;
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'on') ;
            end
                
                
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes_1,'off')
            axis(app.UIAxes_2,'off')
            axis(app.UIAxes_3,'off')
            
            % Place the lovely golden bell in the Ring buttons
            app.RingButton_1.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_2.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_3.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_1.IconAlignment = "top";
            app.RingButton_2.IconAlignment = "top";
            app.RingButton_3.IconAlignment = "top";
            
            %allow player 1 to go
            app.player1Turn = true;
           
            % Starts New Game
            app.newGame()
        end

        % Button pushed function: NewGameButton
        function newGame(app, event)
% New Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % Initialize player's hands and deal the cards
            app.player1 = [];
            app.player2 = [];
            app.player3 = [];
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
            
            % Initialize the played decks (face up cards)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
            
            % Update total cards counter
            app.Counter1.Text = num2str(length(app.player1));
            app.Counter2.Text = num2str(length(app.player2));
            app.Counter3.Text = num2str(length(app.player3));
            
            % Removes cards being displayed
            app.ClearImages()
            
            % Color player1's PlayCardButton and grey out the others
            app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
            app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
        end

        % Button pushed function: PlayCardButton_1
        function PlayCardButton_1Pushed(app, event)
            % Endgame if player1 has no cards
            if length(app.player1) == 0
                Endgame(app,event);
            end
            
            % Should read 


            if app.player1Turn == true
            
                x = showCardImage1(app, event);
                %thingSpeakWrite(app.channelID, 'Fields',1,'Values', x, 'WriteKey', app.writeAPIkey);
                app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
                app.player1(1) = [];
                app.Counter(app.Counter1,app.player1)
                
                %Record information on thingSpeak
                pause(2)
                thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', app.PlayedDeck_1(end), 'WriteKey', app.writeAPIkey) ;
                pause(2)
                thingSpeakRead(app.channelID, 'Fields', [2,3]);
                pause(2)

                
                % Move colored button to indicate player2's turn
                app.PlayCardButton_1.BackgroundColor = [0.94,0.94,0.94];
                app.PlayCardButton_2.BackgroundColor = [0.93,0.69,0.13];
                
                % setting this to true helps run the playerTurn function
                % update: I stopped using the playerTurn function because it wasn't
                % working, but I'm leaving this here for now in case it's
                % helpful later
                app.playCardButton1 = true;


            end
        
            app.player1Turn = false;
            app.player2Turn = true;
            
            
            % Allow player 2 to go next

            
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 4, 'Values', app.player1Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 5, 'Values', app.player2Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            
            thingSpeakRead(app.channelID, 'Fields', (4:6));
            pause(2)
            
            
        end

        % Button pushed function: PlayCardButton_2
        function PlayCardButton_2Pushed(app, event)
            % Endgame if player2 has no cards
            if length(app.player2) == 0
                Endgame(app,event);
            end
           
            
            if app.player2Turn == true

                showCardImage2(app, event)
                app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
                app.player2(1) = [];
                app.Counter(app.Counter2,app.player2)
                
                % Record information on thingSpeak
                pause(2)
                thingSpeakWrite(app.channelID, 'Fields', 2, 'Values', app.PlayedDeck_2(end), 'WriteKey', app.writeAPIkey) ;
                pause(2)
                thingSpeakRead(app.channelID, 'Fields', [1,3]);

                
                % Move colored button to indicate player3's turn
                app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
                app.PlayCardButton_3.BackgroundColor = [0.93,0.69,0.13];
                
                app.playCardButton2 = true;
%                 pause(2)
%                 %thingspeakRun(app, 2, app.playCardButton2)
%                 thingSpeakWrite(app.channelID, 'Fields', 5, 'Values', app.playCardButton2, 'WriteKey', app.writeAPIkey);
%                 %thingspeakGet(app, [1,3])
%                 pause(2)
%                 thingSpeakRead(app.channelID, 'Fields', [4,6]);
            end
            
            
            % playerTurn(app)
            
            app.player2Turn = false;
            app.player3Turn = true;
            
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 5, 'Values', app.player2Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 6, 'Values', app.player3Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            
            thingSpeakRead(app.channelID, 'Fields', (4:6));
            pause(2)
            
        end

        % Button pushed function: PlayCardButton_3
        function PlayCardButton_3Pushed(app, event)
            % Endgame if player3 has no cards
            if length(app.player3) == 0
                Endgame(app,event);
            end
            

            if app.player3Turn == true
            
                showCardImage3(app, event)
                app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
                app.player3(1) = [];
                app.Counter(app.Counter3,app.player3)
                
                % Record information on thingSpeak
                thingSpeakWrite(app.channelID, 'Fields', 3, 'Values', app.PlayedDeck_3(end), 'WriteKey', app.writeAPIkey) ;
                thingSpeakRead(app.channelID, 'Fields', [1,2]);

                % Move colored button to indicate player3's turn
                app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
                app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
                
                app.playCardButton3 = true;
%                 %thingspeakRun(app, 3, app.playCardButton3)
%                 pause(2)
%                 thingSpeakWrite(app.channelID, 'Fields', 6, 'Values', app.playCardButton3, 'WriteKey', app.writeAPIkey);
%                 
%                 %thingspeakGet(app, [1,2])
%                 pause(2)
%                 thingSpeakRead(app.channelID, 'Fields', [4,5]);
            end
                
            % Allow player 1 to go next
            %playerTurn(app)
            
            app.player3Turn = false;
            app.player1Turn = true;
            
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 6, 'Values', app.player3Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            thingSpeakWrite(app.channelID, 'Fields', 4, 'Values', app.player1Turn, 'WriteKey', app.writeAPIkey);
            pause(2)  
            
            thingSpeakRead(app.channelID, 'Fields', (4:6));
            pause(2)
        end

        % Button pushed function: RingButton_1
        function ringBell_1(app, event)
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = [];
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            
            app.BannerLabel.Text = "Player 1 rang the bell,";
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player1 collects allPlayedCards
                 app.player1 = [app.player1, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 app.player2 = [app.player2, app.player1(2)];
                 app.player1(2) = [];
                 app.player3 = [app.player3, app.player1(3)];
                 app.player1(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter3,app.player3)
        end

        % Button pushed function: RingButton_2
        function ringBell_2(app, event)
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = []; 
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            
            app.BannerLabel.Text = "Player 2 rang the bell,";
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player2 collects allPlayedCards
                 app.player2 = [app.player2, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 
              % penalize the player
                 app.player1 = [app.player1, app.player2(2)] ;
                 app.player2(2) = [];
                 app.player3 = [app.player3, app.player2(3)] ;
                 app.player2(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter3,app.player3)
        end

        % Button pushed function: RingButton_3
        function ringBell_3(app, event)
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = []; 
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            
            app.BannerLabel.Text = "Player 3 rang the bell,";
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player3 collects allPlayedCards
                 app.player3 = [app.player3, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 app.player1 = [app.player1, app.player3(2)] ;
                 app.player3(2) = [];
                 app.player2 = [app.player2, app.player3(3)] ;
                 app.player3(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter3,app.player3)
        end

        % Button pushed function: ExitGameButton
        function ExitGame(app, event)
            delete(app)
        end

        % Callback function: RulesButton, RulesText
        function RulesButtonPushed(app, event)
            if (app.RulesText.Visible == "off")
                app.RulesText.Visible = "on";
            else app.RulesText.Visible = "off";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % button used to confirm one's player number before start of
            % the game
            playerchoice = app.PlayerChoice.Value ;
            set(app.PlayerChoice, 'Enable', 'off') ;
            set(app.ConfirmButton, 'Enable', 'off') ; 
            
            if playerchoice == 'Player 1'
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
                set(app.RingButton_2, 'Enable', 'off') ;
                set(app.RingButton_3, 'Enable', 'off') ;
            elseif playerchoice == 'Player 2'
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
                set(app.RingButton_1, 'Enable', 'off') ;
                set(app.RingButton_3, 'Enable', 'off') ;
            elseif playerchoice == 'Player 3'
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.RingButton_1, 'Enable', 'off') ;
                set(app.RingButton_2, 'Enable', 'off') ;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CardGame23UIFigure = uifigure('Visible', 'off');
            app.CardGame23UIFigure.Name = 'Card Game 23';
            app.UIAxes_1 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.YTick = [0 0.5 1];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.YTick = [0 0.5 1];
            app.UIAxes_3.Visible = 'off';
            app.RingButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_1.ButtonPushedFcn = createCallbackFcn(app, @ringBell_1, true);
            app.RingButton_1.Icon = 'golden_bell.jpeg';
            app.RingButton_1.Text = 'Ring';
            app.PlayCardButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_1Pushed, true);
            app.PlayCardButton_1.Text = 'Play Card';
            app.NewGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @newGame, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayCardButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_2Pushed, true);
            app.PlayCardButton_2.Text = 'Play Card';
            app.PlayCardButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_3Pushed, true);
            app.PlayCardButton_3.Text = 'Play Card';
            app.Player1Label = uilabel(app.CardGame23UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.CardGame23UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.CardGame23UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.HalliGalli_Title = uilabel(app.CardGame23UIFigure);
            app.HalliGalli_Title.Tooltip = {''};
            app.HalliGalli_Title.Text = 'Halli Galli';
            app.RingButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ringBell_2, true);
            app.RingButton_2.Icon = 'golden_bell.jpeg';
            app.RingButton_2.Text = 'Ring';
            app.RingButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_3.ButtonPushedFcn = createCallbackFcn(app, @ringBell_3, true);
            app.RingButton_3.Icon = 'golden_bell.jpeg';
            app.RingButton_3.Text = 'Ring';
            app.Counter1 = uilabel(app.CardGame23UIFigure);
            app.Counter1.Text = '0';
            app.Counter2 = uilabel(app.CardGame23UIFigure);
            app.Counter2.Text = '0';
            app.Counter3 = uilabel(app.CardGame23UIFigure);
            app.Counter3.Text = '0';
            app.TotalcardsLabel_1 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_1.Text = 'Total cards:';
            app.TotalcardsLabel_2 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_2.Text = 'Total cards:';
            app.TotalcardsLabel_3 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_3.Text = 'Total cards:';
            app.BannerLabel = uilabel(app.CardGame23UIFigure);
            app.BannerLabel.Text = {''; ''};
            app.ExitGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGame, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.BannerLabel_2 = uilabel(app.CardGame23UIFigure);
            app.BannerLabel_2.Text = {''; ''};
            app.RulesButton = uibutton(app.CardGame23UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.RulesText = uitextarea(app.CardGame23UIFigure);
            app.RulesText.ValueChangedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesText.Editable = 'off';
            app.RulesText.Visible = 'off';
            app.RulesText.Value = {'Rules: '; ''; 'Each player will play 1 card at a time. Once a player sees 5 of at least one type of fruit, they should ring their bell! '; 'Although, if the bell is rung at the wrong time, then the player who rang the bell gives one card to the other players. '; ''; 'How to win: The player with the most cards at the end of the game wins!'};
            app.ChoosewhichplayeryouwouldliketobeDropDownLabel = uilabel(app.CardGame23UIFigure);
            app.ChoosewhichplayeryouwouldliketobeDropDownLabel.Text = 'Choose which player you would like to be :';
            app.PlayerChoice = uidropdown(app.CardGame23UIFigure);
            app.PlayerChoice.Items = {'Player 1', 'Player 2', 'Player 3'};
            app.PlayerChoice.Value = 'Player 1';
            app.ConfirmButton = uibutton(app.CardGame23UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.CardGame23UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft_Thingspeak3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CardGame23UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardGame23UIFigure)
        end
    end
end