classdef FinalProjectDraft_ThingspeakComment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardGame23UIFigure  matlab.ui.Figure
        RingButton_1        matlab.ui.control.Button
        PlayCardButton_1    matlab.ui.control.Button
        NewGameButton       matlab.ui.control.Button
        PlayCardButton_2    matlab.ui.control.Button
        PlayCardButton_3    matlab.ui.control.Button
        Player1Label        matlab.ui.control.Label
        Player2Label        matlab.ui.control.Label
        Player3Label        matlab.ui.control.Label
        HalliGalli_Title    matlab.ui.control.Label
        RingButton_2        matlab.ui.control.Button
        RingButton_3        matlab.ui.control.Button
        Counter1            matlab.ui.control.Label
        Counter2            matlab.ui.control.Label
        Counter3            matlab.ui.control.Label
        TotalcardsLabel_1   matlab.ui.control.Label
        TotalcardsLabel_2   matlab.ui.control.Label
        TotalcardsLabel_3   matlab.ui.control.Label
        BannerLabel         matlab.ui.control.Label
        ExitGameButton      matlab.ui.control.Button
        BannerLabel_2       matlab.ui.control.Label
        RulesButton         matlab.ui.control.Button
        RulesText           matlab.ui.control.TextArea
        ChoosewhichplayeryouwouldliketobeDropDownLabel  matlab.ui.control.Label
        PlayerChoice        matlab.ui.control.DropDown
        ConfirmButton       matlab.ui.control.Button
        UIAxes_1            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
        PlayedDeck_1 % player1's played deck of cards
        PlayedDeck_2 % player2's played deck of cards
        PlayedDeck_3 % player3's played deck of cards
        playCardButton1 % Player 1 played a card (Boolean)
        playCardButton2 % Player 2 played a card (Boolean)
        playCardButton3 % Player 3 played a card (Boolean)
%         player1Turn % Player 1's turn (Boolean)
%         player2Turn % Player 2's turn (Boolean)
%         player3Turn % Player 3's turn (Boolean)
    end
    
    properties (Access = public)
        channelID = 1250202 % ID for the first channel
        channelID2 = 1255315 % ID for the second channel
        channelID3 = 1256137 % ID for the third channel
        writeAPIkey = "FLMRWKXF42OL7L1V" % Write API for channel 1
        writeAPIkey2 = "CZ2QEF3IDQR53EH9" % Write API for channel 2
        writeAPIkey3 = "6HRTTWM2JEXTI42F" % Write API for channel 3
        readAPIkey = "VDAO38SN46G4W16T" % Read API for channel 1
        readAPIkey2 = "G951IGR5G7960Z7R" % Read API for channel 2
        readAPIkey3 = "HEOJL3V81FGP7IYU" % Read API for channel 3
    end
    
    methods (Access = private)
        
        function results = Counter(app,Counter,player)
            % This function updates the counter for the cards
            Counter.Text =  num2str(length(player));
        end
        
        function card = showCardImage1(app, event)
            % This function shows the card image for the first player
            png = ".png";
            dash = "-";    
           
            cardVal = app.player1(1);

            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);   
            end
        end
        
        function card = showCardImage2(app,event)
            % This function shows the card image for player 2
            png = ".png";
            dash = "-";
            %x = thingSpeakRead(app.channelID, 'Fields', 2);
            cardVal = app.player2(1);
            %cardVal = x;
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            end
        end
        
        function card = showCardImage3(app,event)
            % This function shows the card image for player 3
            png = ".png";
            dash = "-";
            
            cardVal = app.player3(1);

            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            end
        end
        
        function results = ClearImages(app)
            imshow([0, 0, 0], 'Parent', app.UIAxes_1);
            imshow([0, 0, 0], 'Parent', app.UIAxes_2);
            imshow([0, 0, 0], 'Parent', app.UIAxes_3);
        end
        
        function fruit = DetermineFruit(app, player)
            cardVal = player(1);
            if (0 < cardVal) && (cardVal <= 5)
                fruit = "apple";
            elseif (5 < cardVal) && (cardVal <= 10)
                fruit = "strawberry";
            elseif (11 < cardVal) && (cardVal <= 15)
                fruit = "lime";
            else % cardVal is 16-20
                fruit = "banana";
            end
        end
        
        function value = fruitValue(app, player)
            value = mod(player,5)+1;
        end
        
        function fiveFruit = EvaluateCards(app, event)
            % 'fiveFruit' will return 1 if the player rang bell 
            
            % player1's faceup card
            if isempty(app.PlayedDeck_1)
                fruit1 = "";
                value1 = 0;
            else
                fruit1 = DetermineFruit(app, app.PlayedDeck_1(end));
                value1 = fruitValue(app, app.PlayedDeck_1(end));
            end

            % player2's faceup card
            if isempty(app.PlayedDeck_2)
                fruit2 = "";
                value2 = 0;
            else
                fruit2 = DetermineFruit(app, app.PlayedDeck_2(end));
                value2 = fruitValue(app, app.PlayedDeck_2(end));
            end
            
            % player3's faceup card
            if isempty(app.PlayedDeck_3)
                fruit3 = "";
                value3 = 0;
            else
                fruit3 = DetermineFruit(app, app.PlayedDeck_3(end));
                value3 = fruitValue(app, app.PlayedDeck_3(end));
            end
            
            % create 2-d array of the fruits of the cards displayed along with
            % their values
            cardsDisplayed = [fruit1, fruit2, fruit3; value1, value2, value3];
            total1 = 0;
            total2 = 0;
            total3 = 0;
            
            
            % sums up the values of the cards that are displayed that are
            % the same fruit
            for i = 1:3
                if cardsDisplayed(1,i) == fruit1
                    total1 = total1 + str2double(cardsDisplayed(2,i));
                elseif cardsDisplayed(1,i) == fruit2
                    total2 = total2 + str2double(cardsDisplayed(2,i));
                elseif cardsDisplayed(1,i) == fruit3
                    total3 = total3 + str2double(cardsDisplayed(2,i));
                end
            end
            
            % Initialize the fiveFruit logical array (1x1)
            fiveFruit = [];
                
            % Are the if statements below evaluating ALL options? 
            % (i.e.) fruit1 == fruit2 && fruit1 == fruit3 possibility?
            
            if total1 == 5
                fiveFruit = true;
            elseif total2 == 5
                fiveFruit = true;
            elseif total3 == 5
                fiveFruit = true;
            end
            

        end
        
        function results = ClearPlayedDecks(app, event)
            % Clears the played card decks for all players
            ClearImages(app)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
        end
        
        function results = Endgame(app, event)
            % WinFun function to execute when a player has 0 cards left 
            % in their hand, determine the player with the most cards
            % and display a winner banner over their UIAxes.
            numCards1 = length(app.player1);
            numCards2 = length(app.player2);
            numCards3 = length(app.player3);
            
            if (numCards1 == 0 || numCards2 == 0 || numCards3 == 0)
                app.BannerLabel_2.Text = "End of Game!";

            end
            
%             % To be displayed over winning player
%             app.BannerLabel.FontColor = [0.39, 0.83, 0.07]; % Color text green
%             app.BannerLabel.FontSize = 30;
%             app.BannerLabel.Text = "WINNER!!!";
        end
        
        
        function results = counternum(app,Counter,num)
            Counter.Text =  num2str(num);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes_1,'off')
            axis(app.UIAxes_2,'off')
            axis(app.UIAxes_3,'off')
            
            % Place the lovely golden bell in the Ring buttons
            app.RingButton_1.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_2.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_3.Icon = imread("golden_bell.jpeg", "jpeg");
            app.RingButton_1.IconAlignment = "top";
            app.RingButton_2.IconAlignment = "top";
            app.RingButton_3.IconAlignment = "top";
           
           
            % Starts New Game
            app.newGame()
        end

        % Button pushed function: NewGameButton
        function newGame(app, event)
% New Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % Initialize player's hands and deal the cards
            app.player1 = [];
            app.player2 = [];
            app.player3 = [];
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
            
            % Initialize the played decks (face up cards)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
            
            % Update total cards counter
            app.Counter1.Text = num2str(length(app.player1));
            app.Counter2.Text = num2str(length(app.player2));
            app.Counter3.Text = num2str(length(app.player3));
            
            % Removes cards being displayed
            app.ClearImages()
            
            % Color player1's PlayCardButton and grey out the others
            app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
            app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
            
            %allow player 1 to go
            player1Turn = 1;
            player2Turn = 0;
            player3Turn = 0;


            % send value to allow player 1 to go to thingspeak
            % Writes to channel 1, field 4, which are receives 0 or 1 based
            % on if it is player 1's turn or not
            pause(1)
            thingSpeakWrite(app.channelID, 'Fields', 4, 'Values', player1Turn, 'WriteKey', app.writeAPIkey);
            pause(2)
            
            % Writes to channel 2, so we don't have to worry about sending
            % nan to other fields
            % Sends values to fields 1,2,3 the number of cards in each of
            % the player's decks
            thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [20,20,20], 'WriteKey', app.writeAPIkey2);
            pause(1)
            thingSpeakWrite(app.channelID3, 'Fields', [1,2,3], 'Values', [nan,nan,nan], 'WriteKey', app.writeAPIkey3);

%             
        end

        % Button pushed function: PlayCardButton_1
        function PlayCardButton_1Pushed(app, event)
            % Endgame if player1 has no cards
            if length(app.player1) == 0
                Endgame(app,event);
            end
            

            pause(1)
            % reads player 1's assigned turn field from thingspeak as a
            % matrix
            
            % returns a matrix of the values of player 1 turn's field
            datamat = thingSpeakRead(app.channelID, 'Fields', 4, 'OutputFormat','matrix');
            pause(1)
            
            % returns a matrix of the cards the other two player's have
            % played
            cards = thingSpeakRead(app.channelID, 'Fields', [2,3], 'OutputFormat','matrix');
            pause(1)
            
            % Returns a matrix of numbers for the other two player's
            % remaining count of cards
            counters = thingSpeakRead(app.channelID2, 'Fields', [2,3], 'OutputFormat','matrix');
            pause(1)
            playerlostcards = thingSpeakRead(app.channelID3, 'Fields', [1,2,3], 'OutputFormat','matrix');
            

            % reads first element of the matrix of values of turn field
            % to get the value 
            playerturn = datamat(1);
            
            if playerturn == 1
                if ~isempty(playerlostcards) && ~isnan(playerlostcards(1))
                    app.player1 = [app.player1, playerlostcards(1)];
                    thingSpeakWrite(app.channelID3, 'Fields', [1,2,3], 'Values', [nan,playerlostcards(2),playerlostcards(3)], 'WriteKey', app.writeAPIkey3);
                end
                % changes color of player 1 play button first
                app.PlayCardButton_1.BackgroundColor = [0.94,0.94,0.94];
                
                % shows the image in the deck
                showCardImage1(app, event);
                % puts played card to the back of the played deck of cards
                app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
                app.player1(1) = [];
                % updates the counters
                app.Counter(app.Counter1,app.player1)
                counter2 = counters(1); 
                counter3 = counters(2);


                % Set player 2's playerturn field to 1, so they can go
                % next

                pause(2)
                thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [app.PlayedDeck_1(end),cards(1),cards(2),0,1,0], 'WriteKey', app.writeAPIkey);
                
                % Record the length of each of the played card 
                thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [length(app.player1),counter2,counter3], 'WriteKey', app.writeAPIkey2);
                
                
                pause(2)
                
                % will display the other two player's cards and update
                if ~isnan(cards(1))

                app.player2 = [cards(1),app.player2];
                showCardImage2(app,event)
                app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
                counternum(app,app.Counter2,counters(1))
                
                
                end
                
                if ~isnan(cards(2))
                app.player3 = [cards(2),app.player3];
                showCardImage3(app,event)
                app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
                counternum(app,app.Counter3,counters(2))

                end


            else % if it's not player 1's turn
                app.BannerLabel.Text = "Not Your Turn!";
                pause(3)
                app.BannerLabel.Text = "";

            end
            
            % Move colored button to indicate player2's turn

            app.PlayCardButton_2.BackgroundColor = [0.93,0.69,0.13];


        end

        % Button pushed function: PlayCardButton_2
        function PlayCardButton_2Pushed(app, event)
            % Endgame if player2 has no cards
            if length(app.player2) == 0
                Endgame(app,event);
            end
            pause(1)
            
            % reads player 2's assigned turn field from thingspeak as a
            % matrix
            datamat = thingSpeakRead(app.channelID, 'Fields', 5, 'OutputFormat','matrix');
            pause(1)
            
                        
            % returns a matrix of the cards the other two player's have
            % played
            cards = thingSpeakRead(app.channelID, 'Fields', [3,1], 'OutputFormat','matrix');
            pause(1)
            
            % Returns a matrix of numbers for the other two player's
            % remaining count of cards
            counters = thingSpeakRead(app.channelID2, 'Fields', [3,1], 'OutputFormat','matrix');
            pause(1)
            playerlostcards = thingSpeakRead(app.channelID3, 'Fields', [1,2,3], 'OutputFormat','matrix');
            
            % reads first element of the matrix to get the value 
            playerturn = datamat(1);


            if playerturn == 1
                if ~isempty(playerlostcards) && ~isnan(playerlostcards(2))
                    app.player2 = [app.player2, playerlostcards(1)];
                    thingSpeakWrite(app.channelID3, 'Fields', [1,2,3], 'Values', [playerlostcards(1),nan,playerlostcards(3)], 'WriteKey', app.writeAPIkey3);
                end
                %takes away color first
                app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
                
                % shows the image in the deck
                showCardImage2(app, event)
                
                % puts played card to the back of the played deck of cards
                app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
                app.player2(1) = [];
                                % updates the counters
                app.Counter(app.Counter2,app.player2)
                
                
                % Move colored button to indicate player3's turn
                app.PlayCardButton_3.BackgroundColor = [0.93,0.69,0.13];
                
                % Set player 3's turn field to 1 to allow them to go next
                %
                pause(1)
                thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [cards(2),app.PlayedDeck_2(end),cards(1),0,0,1], 'WriteKey', app.writeAPIkey);
                
                % sends the the counter values to thingspeak
                pause(1)
                thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [counters(2),length(app.player2),counters(1)], 'WriteKey', app.writeAPIkey2);                
                pause(1)
               
                % shows the image of played card for the other two
                % players
                if ~isnan(cards(1))
                app.player3 = [cards(1),app.player3];
                showCardImage3(app,event)
                app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
                counternum(app,app.Counter3,counters(1))                
                end
                
                if ~isnan(cards(2))
                app.player1 = [cards(2),app.player1];
                showCardImage1(app,event)
                app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
                counternum(app,app.Counter1,counters(2))
                end

            else
                app.BannerLabel.Text = "Not Your Turn!";
                pause(3)
                app.BannerLabel.Text = "";


            end
            
            
        end

        % Button pushed function: PlayCardButton_3
        function PlayCardButton_3Pushed(app, event)
            % Endgame if player3 has no cards
            if length(app.player3) == 0
                Endgame(app,event);
            end
            pause(1)
            %%% This function is entirely the same as the previous two
            %%% playcardbutton functions, just with player 3
            
            datamat = thingSpeakRead(app.channelID, 'Fields', 6, "OutputFormat","matrix");
            pause(1)
            cards = thingSpeakRead(app.channelID, 'Fields', [1,2], 'OutputFormat','matrix');
            pause(1)
            counters = thingSpeakRead(app.channelID2, 'Fields', [1,2], 'OutputFormat','matrix');
            pause(1)
            playerlostcards = thingSpeakRead(app.channelID3, 'Fields', [1,2,3], 'OutputFormat','matrix');

            playerturn = datamat(1);
            pause(1)


            if playerturn == 1
                if ~isempty(playerlostcards) && ~isnan(playerlostcards(3))
                    app.player3 = [app.player3, playerlostcards(1)];
                    thingSpeakWrite(app.channelID3, 'Fields', [1,2,3], 'Values', [playerlostcards(1),playerlostcards(2),nan], 'WriteKey', app.writeAPIkey3);
                end
                %takes away color from button first
                app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
                showCardImage3(app, event)
                app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
                app.player3(1) = [];
                app.Counter(app.Counter3,app.player3)
   
                % Move colored button to indicate player3's turn

                app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
                
                thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [cards(1),cards(2),app.PlayedDeck_3(end),1,0,0], 'WriteKey', app.writeAPIkey);
                thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [counters(1),counters(2),length(app.player3)], 'WriteKey', app.writeAPIkey2);
                %Record information on thingSpeak
                pause(2)


                if ~isnan(cards(1))
                app.player1 = [cards(1),app.player1];
                showCardImage1(app,event)
                app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
                counternum(app,app.Counter1,counters(1))
                end
                
                if ~isnan(cards(2))
                app.player2 = [cards(2),app.player2];
                showCardImage2(app,event)
                app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
                counternum(app,app.Counter2,counters(2))                
                end   

            else
                app.BannerLabel.Text = "Not Your Turn!";
                pause(3)
                app.BannerLabel.Text = "";

            end

        end

        % Button pushed function: RingButton_1
        function ringBell_1(app, event)
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = [];
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            counters = thingSpeakRead(app.channelID2, 'Fields', [2,3], 'OutputFormat','matrix');
            app.BannerLabel.Text = "Player 1 rang the bell,";
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player1 collects allPlayedCards
                 app.player1 = [app.player1, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
                 
                 % sends the correct turn values to player 1
                 thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [nan,nan,nan,1,0,0], 'WriteKey', app.writeAPIkey);
                 pause(1)
            else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 thingSpeakWrite(app.channelID3, 'Fields', [2,3], 'Values', [app.player1(end),app.player1(end-1)], 'WriteKey', app.writeAPIkey3);
                 app.player1(1) = [];
                 app.player1(1) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [length(app.player1),counters(1),counters(2)], 'WriteKey', app.writeAPIkey2);
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             counternum(app,app.Counter2,counters(1))
             counternum(app,app.Counter3,counters(2))
        end

        % Button pushed function: RingButton_2
        function ringBell_2(app, event)
            % the function is exactly the same as the previous ringbutton
            % function, just for player 2
            
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = []; 
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            
            app.BannerLabel.Text = "Player 2 rang the bell,";
            counters = thingSpeakRead(app.channelID2, 'Fields', [3,1], 'OutputFormat','matrix');

            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player2 collects allPlayedCards
                 app.player2 = [app.player2, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
                 
                 thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [nan,nan,nan,0,1,0], 'WriteKey', app.writeAPIkey);
                 pause(1)
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 
              % penalize the player
                 thingSpeakWrite(app.channelID3, 'Fields', [3,1], 'Values', [app.player1(end),app.player1(end-1)], 'WriteKey', app.writeAPIkey3);
                 app.player2(1) = [];
                 app.player2(1) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [counters(2),length(app.player2),counters(1)], 'WriteKey', app.writeAPIkey2);
             
             % Update Counters
             counternum(app,app.Counter3,counters(1))
             app.Counter(app.Counter2,app.player2)
             counternum(app,app.Counter1,counters(2))
        end

        % Button pushed function: RingButton_3
        function ringBell_3(app, event)
            % the function is exactly the same as the previous ringbutton
            % function, just for player 2
            
            % Ring the bell sound
            [y, Fs] = audioread('Bell_Sound.mp3');
            y(1:0.7*Fs,:) = []; 
            sound(y, Fs);
%           Bell sound source : https://www.youtube.com/watch?v=qBw-OmBv9o8
            
            app.BannerLabel.Text = "Player 3 rang the bell,";
            counters = thingSpeakRead(app.channelID2, 'Fields', [1,2], 'OutputFormat','matrix');
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == true
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player3 collects allPlayedCards
                 app.player3 = [app.player3, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
                 
                 thingSpeakWrite(app.channelID, 'Fields', [1,2,3,4,5,6], 'Values', [nan,nan,nan,0,0,1], 'WriteKey', app.writeAPIkey);
                 pause(1)
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 thingSpeakWrite(app.channelID3, 'Fields', [1,2], 'Values', [app.player1(end),app.player1(end-1)], 'WriteKey', app.writeAPIkey3);
                 app.player3(1) = [];
                 app.player3(1) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             thingSpeakWrite(app.channelID2, 'Fields', [1,2,3], 'Values', [counters(1),counters(2),length(app.player3)], 'WriteKey', app.writeAPIkey2);

             
             % Update Counters
                counternum(app,app.Counter1,counters(1))
                counternum(app,app.Counter2,counters(2))
                app.Counter(app.Counter3,app.player3)
        end

        % Button pushed function: ExitGameButton
        function ExitGame(app, event)
            delete(app)
        end

        % Callback function: RulesButton, RulesText
        function RulesButtonPushed(app, event)
            if (app.RulesText.Visible == "off")
                app.RulesText.Visible = "on";
            else app.RulesText.Visible = "off";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % button used to confirm one's player number before start of
            % the game
            playerchoice = app.PlayerChoice.Value ;
            set(app.PlayerChoice, 'Enable', 'off') ;
            set(app.ConfirmButton, 'Enable', 'off') ; 
            
            if playerchoice == 'Player 1'
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
                set(app.UIAxes_1, 'Visible', 'on')
                set(app.UIAxes_2, 'Visible', 'on')
                set(app.UIAxes_3, 'Visible', 'on')
                set(app.RingButton_2, 'Enable', 'off') ;
                set(app.RingButton_3, 'Enable', 'off') ;
            elseif playerchoice == 'Player 2'
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_3, 'Enable', 'off') ;
                set(app.UIAxes_1, 'Visible', 'on')
                set(app.UIAxes_2, 'Visible', 'on')
                set(app.UIAxes_3, 'Visible', 'on')
                set(app.RingButton_1, 'Enable', 'off') ;
                set(app.RingButton_3, 'Enable', 'off') ;
            elseif playerchoice == 'Player 3'
                set(app.PlayCardButton_1, 'Enable', 'off') ;
                set(app.PlayCardButton_2, 'Enable', 'off') ;
                set(app.UIAxes_1, 'Visible', 'on')
                set(app.UIAxes_2, 'Visible', 'on')
                set(app.UIAxes_3, 'Visible', 'on')
                set(app.RingButton_1, 'Enable', 'off') ;
                set(app.RingButton_2, 'Enable', 'off') ;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CardGame23UIFigure = uifigure('Visible', 'off');
            app.CardGame23UIFigure.Name = 'Card Game 23';
            app.RingButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_1.ButtonPushedFcn = createCallbackFcn(app, @ringBell_1, true);
            app.RingButton_1.Icon = 'golden_bell.jpeg';
            app.RingButton_1.Text = 'Ring';
            app.PlayCardButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_1Pushed, true);
            app.PlayCardButton_1.Text = 'Play Card';
            app.NewGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @newGame, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayCardButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_2Pushed, true);
            app.PlayCardButton_2.Text = 'Play Card';
            app.PlayCardButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_3Pushed, true);
            app.PlayCardButton_3.Text = 'Play Card';
            app.Player1Label = uilabel(app.CardGame23UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.CardGame23UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.CardGame23UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.HalliGalli_Title = uilabel(app.CardGame23UIFigure);
            app.HalliGalli_Title.Tooltip = {''};
            app.HalliGalli_Title.Text = 'Halli Galli';
            app.RingButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ringBell_2, true);
            app.RingButton_2.Icon = 'golden_bell.jpeg';
            app.RingButton_2.Text = 'Ring';
            app.RingButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_3.ButtonPushedFcn = createCallbackFcn(app, @ringBell_3, true);
            app.RingButton_3.Icon = 'golden_bell.jpeg';
            app.RingButton_3.Text = 'Ring';
            app.Counter1 = uilabel(app.CardGame23UIFigure);
            app.Counter1.Text = '0';
            app.Counter2 = uilabel(app.CardGame23UIFigure);
            app.Counter2.Text = '0';
            app.Counter3 = uilabel(app.CardGame23UIFigure);
            app.Counter3.Text = '0';
            app.TotalcardsLabel_1 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_1.Text = 'Total cards:';
            app.TotalcardsLabel_2 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_2.Text = 'Total cards:';
            app.TotalcardsLabel_3 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_3.Text = 'Total cards:';
            app.BannerLabel = uilabel(app.CardGame23UIFigure);
            app.BannerLabel.Text = {''; ''};
            app.ExitGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGame, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.BannerLabel_2 = uilabel(app.CardGame23UIFigure);
            app.BannerLabel_2.Text = {''; ''};
            app.RulesButton = uibutton(app.CardGame23UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.RulesText = uitextarea(app.CardGame23UIFigure);
            app.RulesText.ValueChangedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesText.Editable = 'off';
            app.RulesText.Visible = 'off';
            app.RulesText.Value = {'Rules: '; ''; 'Each player will play 1 card at a time. Once a player sees 5 of at least one type of fruit, they should ring their bell! '; 'Although, if the bell is rung at the wrong time, then the player who rang the bell gives one card to the other players. '; ''; 'How to win: The player with the most cards at the end of the game wins!'};
            app.ChoosewhichplayeryouwouldliketobeDropDownLabel = uilabel(app.CardGame23UIFigure);
            app.ChoosewhichplayeryouwouldliketobeDropDownLabel.Text = 'Choose which player you would like to be :';
            app.PlayerChoice = uidropdown(app.CardGame23UIFigure);
            app.PlayerChoice.Items = {'Player 1', 'Player 2', 'Player 3'};
            app.PlayerChoice.Value = 'Player 1';
            app.ConfirmButton = uibutton(app.CardGame23UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.UIAxes_1 = uiaxes(app.CardGame23UIFigure);
            app.UIAxes_1.YTick = [0 0.5 1];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.CardGame23UIFigure);
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.CardGame23UIFigure);
            app.UIAxes_3.YTick = [0 0.5 1];
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.CardGame23UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft_ThingspeakComment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CardGame23UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardGame23UIFigure)
        end
    end
end