classdef FinalProjectDraft_LeiBell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardGame23UIFigure   matlab.ui.Figure
        UIAxes_1             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        RingButton_1         matlab.ui.control.Button
        PlayCardButton_1     matlab.ui.control.Button
        NewGameButton        matlab.ui.control.Button
        PlayCardButton_2     matlab.ui.control.Button
        PlayCardButton_3     matlab.ui.control.Button
        Player1Label         matlab.ui.control.Label
        Player2Label         matlab.ui.control.Label
        Player3Label         matlab.ui.control.Label
        HalliGalli_Title     matlab.ui.control.Label
        RingButton_2         matlab.ui.control.Button
        RingButton_3         matlab.ui.control.Button
        Counter1             matlab.ui.control.Label
        Counter2             matlab.ui.control.Label
        Counter3             matlab.ui.control.Label
        TotalcardsLabel_1    matlab.ui.control.Label
        TotalcardsLabel_2    matlab.ui.control.Label
        TotalcardsLabel_3    matlab.ui.control.Label
        Counter1_storedDeck  matlab.ui.control.Label
        Counter2_storedDeck  matlab.ui.control.Label
        Counter3_storedDeck  matlab.ui.control.Label
        BannerLabel          matlab.ui.control.Label
        ExitGameButton       matlab.ui.control.Button
        BannerLabel_2        matlab.ui.control.Label
        Label_1              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        UpdatelabelsButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        deck % deck of cards, no shuffling
        shuffledCards % deck of cards after shuffling
        player1 % player1's deck of cards 
        player2 % player2's deck of cards
        player3 % player3's deck of cards
        PlayedDeck_1 % player1's played deck of cards
        PlayedDeck_2 % player2's played deck of cards
        PlayedDeck_3 % player3's played deck of cards
    end
    
    methods (Access = private)
                
        function results = Counter(app,Counter,player)
            Counter.Text =  num2str(length(player));
        end
        
        function results = showCardImage1(app, event)
            png = ".png";
            dash = "-";    

            % while player 1's turn == true
            cardVal = app.player1(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_1);
                ylim(app.UIAxes_1, [0, dim(1)]);
                xlim(app.UIAxes_1, [0, dim(2)]);   
            end
        end
        
        function results = showCardImage2(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player2(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_2);
                ylim(app.UIAxes_2, [0, dim(1)]);
                xlim(app.UIAxes_2, [0, dim(2)]);
            end
        end
        
        function results = showCardImage3(app,event)
            png = ".png";
            dash = "-";
            cardVal = app.player3(1);
            if (0 < cardVal) && (cardVal <= 5)
                suit = "apple";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (5 < cardVal) && (cardVal <= 10)
                suit = "strawberry";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            elseif (11 < cardVal) && (cardVal <= 15)
                suit = "lime";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            else % cardVal is 16-20
                suit = "banana";
                value = num2str(mod(cardVal,5)+1);
                % Code to display the card image
                card = imread(strcat(suit, dash, value, png));
                dim = size(card);
                imshow(card, 'parent', app.UIAxes_3);
                ylim(app.UIAxes_3, [0, dim(1)]);
                xlim(app.UIAxes_3, [0, dim(2)]);
            end
        end
        
        function results = ClearImages(app)
            imshow([0, 0, 0], 'Parent', app.UIAxes_1);
            imshow([0, 0, 0], 'Parent', app.UIAxes_2);
            imshow([0, 0, 0], 'Parent', app.UIAxes_3);
        end
        
        function fruit = DetermineFruit(app, player)
            cardVal = player(1);
            if (0 < cardVal) && (cardVal <= 5)
                fruit = "apple";
            elseif (5 < cardVal) && (cardVal <= 10)
                fruit = "strawberry";
            elseif (11 < cardVal) && (cardVal <= 15)
                fruit = "lime";
            else % cardVal is 16-20
                fruit = "banana";
            end
        end
        
        function value = fruitValue(app, player)
            value = mod(player,5)+1;
        end
        
        function fiveFruit = EvaluateCards(app, event)
            % 'fiveFruit' will return 1 if the player rang bell 
            % when the same fruit present
            
            % player1's faceup card
            fruit1 = DetermineFruit(app, app.player1(1));
            value1 = fruitValue(app, app.player1(1));
           
            % player2's faceup card
            fruit2 = DetermineFruit(app, app.player2(1));
            value2 = fruitValue(app, app.player2(1));
            
            % player3's faceup card
            fruit3 = DetermineFruit(app, app.player3(1));
            value3 = fruitValue(app, app.player3(1));
            
            % create 2-d array
            cardsDisplayed = [fruit1, fruit2, fruit3; value1, value2, value3];
            total1 = 0;
            total2 = 0;
            total3 = 0;
            
            for i = 1:3
                if cardsDisplayed(1,i) == fruit1
                    total1 = total1 + cardsDisplayed(2,i);
                elseif cardsDisplayed(1,i) == fruit2
                    total2 = total2 + cardsDisplayed(2,i);
                elseif cardsDisplayed(1,i) == fruit3
                    total3 = total3 + cardsDisplayed(2,i);
                end
            end
            
            % Initialize the fiveFruit variable
            fiveFruit = 0;
                
            % Are the if statements below evaluating ALL options? 
            % (i.e.) fruit1 == fruit2 && fruit1 == fruit3 possibility?
            
            if str2double(total1) > 4 && str2double(total1) < 6
                fiveFruit = 1;
            elseif str2double(total2) > 4 && str2double(total2) < 6
                fiveFruit = 1;
            elseif str2double(total3) > 4 && str2double(total3) < 6
                fiveFruit = 1;
            end
            
            % This works, but it counts wins for 5 fruits or more.
%             if str2num(total1) >= 5
%                 fiveFruit = 1;
%             elseif str2num(total2) >= 5
%                 fiveFruit = 1;
%             elseif str2num(total3) >= 5
%                 fiveFruit = 1;
%             end
               
%             if (fruit1 == fruit2) && (value1+value2 == 5)
%                  fiveFruit = 1; 
%             elseif (fruit2 == fruit3) && (value3+value2 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 == fruit3) && (value1+value3 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 == fruit2 && fruit1 == fruit3) && (value1+value2+value3 == 5)
%                  fiveFruit = 1;
%             elseif (fruit1 ~= fruit2) && (fruit2 ~= fruit3) && (fruit1 ~= fruit3)
%                 if value1 == 5
%                     fiveFruit = 1;
%                 elseif value2 == 5
%                     fiveFruit = 1;
%                 elseif value3 == 5
%                     fiveFruit = 1;
%                     end
%                 end
        end
        
        function results = ClearPlayedDecks(app, event)
            ClearImages(app)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
        end
        
        function results = Endgame(app, event)
            % WinFun function to determine the player with the most cards
            % and display a winner banner over their cards
            
            % To be displayed over winning player
            app.BannerLabel.FontColor = [0.39,0.83,0.07]; % Color text green
            app.BannerLabel.FontSize = 30;
            app.BannerLabel.Text = "WINNER!!!";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This initializes the deck 
            apple = [1:5];
            strawberry = [6:10];
            lime = [11:15];
            banana = [16:20];
            
            app.deck = [apple, strawberry, lime, banana];
            app.deck = repelem(app.deck, 3); %repeats the 20 cards to make 60 in total
            
            % take off axes on UIAxes when displaying drawn card
            axis(app.UIAxes_1,'off')
            axis(app.UIAxes_2,'off')
            axis(app.UIAxes_3,'off')
            
            % Starts New Game
            app.newGame()
        end

        % Button pushed function: NewGameButton
        function newGame(app, event)
% New Game button shuffles the deck AND deals the cards out to 3 players

            % Shuffle the deck
            [m,n]=size(app.deck);
            num = randperm(n);
            app.shuffledCards = app.deck;
            app.shuffledCards(1,num)=app.deck(1,:);
            
            % Initialize player's hands and deal the cards
            app.player1 = [];
            app.player2 = [];
            app.player3 = [];
            app.player1(1:20)= app.shuffledCards(1:20);
            app.player2(1:20)= app.shuffledCards(21:40);
            app.player3(1:20)= app.shuffledCards(41:60);
            
            % Initialize the played decks (face up cards)
            app.PlayedDeck_1 = [];
            app.PlayedDeck_2 = [];
            app.PlayedDeck_3 = [];
            
            % Update total cards counter
            app.Counter1.Text = num2str(length(app.player1));
            app.Counter2.Text = num2str(length(app.player2));
            app.Counter3.Text = num2str(length(app.player3));
            app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
            app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
            app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
            
            % Removes cards being displayed
            app.ClearImages()
            
            % Color player1's PlayCardButton and grey out the others
            app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
            app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
        end

        % Button pushed function: PlayCardButton_1
        function PlayCardButton_1Pushed(app, event)
            app.PlayedDeck_1 = [app.PlayedDeck_1, app.player1(1)];
            app.player1(1) = [];
            showCardImage1(app, event)
            app.Counter(app.Counter1,app.player1)
            app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
            
            % Move colored button to indicate player2's turn
            app.PlayCardButton_1.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_2.BackgroundColor = [0.93,0.69,0.13];
        end

        % Button pushed function: PlayCardButton_2
        function PlayCardButton_2Pushed(app, event)
            app.PlayedDeck_2 = [app.PlayedDeck_2, app.player2(1)];
            app.player2(1) = [];
            showCardImage2(app, event)
            app.Counter(app.Counter2,app.player2)
            app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
            
            % Move colored button to indicate player3's turn
            app.PlayCardButton_2.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_3.BackgroundColor = [0.93,0.69,0.13];
        end

        % Button pushed function: PlayCardButton_3
        function PlayCardButton_3Pushed(app, event)
            app.PlayedDeck_3 = [app.PlayedDeck_3, app.player3(1)];
            app.player3(1) = [];
            showCardImage3(app, event)
            app.Counter(app.Counter3,app.player3)
            app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
            
            % Move colored button to indicate player3's turn
            app.PlayCardButton_3.BackgroundColor = [0.94,0.94,0.94];
            app.PlayCardButton_1.BackgroundColor = [0.93,0.69,0.13];
        end

        % Button pushed function: RingButton_1
        function ringBell_1(app, event)
            app.BannerLabel.Text = "Player 1 rang the bell,"
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == 1
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player1 collects allPlayedCards
                 app.player1 = [app.player1, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 app.player2 = [app.player2, app.player1(2)] ;
                 app.player1(2) = [];
                 app.player3 = [app.player3, app.player1(3)] ;
                 app.player1(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
             app.Counter(app.Counter3,app.player3)
             app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
        end

        % Button pushed function: RingButton_2
        function ringBell_2(app, event)
            app.BannerLabel.Text = "Player 2 rang the bell,"
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == 1
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player2 collects allPlayedCards
                 app.player2 = [app.player2, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 app.player1 = [app.player1, app.player2(2)] ;
                 app.player2(2) = [];
                 app.player3 = [app.player3, app.player2(3)] ;
                 app.player2(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
             app.Counter(app.Counter3,app.player3)
             app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
        end

        % Button pushed function: RingButton_3
        function ringBell_3(app, event)
            app.BannerLabel.Text = "Player 3 rang the bell,"
            
            fiveFruit = EvaluateCards(app, event);
             if fiveFruit == 1
                 % Played cards are gathered
                 allPlayedCards = [app.PlayedDeck_1, app.PlayedDeck_2, app.PlayedDeck_3];
                 
                 % player3 collects allPlayedCards
                 app.player3 = [app.player3, allPlayedCards];
                 
                 app.BannerLabel_2.Text = "and won the cards!" ;
                 ClearPlayedDecks(app,event);
             else
% This code needs to ensure the correct cards are distributed 
% AND clear out the cards from the player who lost.
                 % penalize the player
                 app.player1 = [app.player1, app.player3(2)] ;
                 app.player3(2) = [];
                 app.player2 = [app.player2, app.player3(3)] ;
                 app.player3(3) = [];
                 app.BannerLabel_2.Text = "but loses one card to each player... :(" ;
             end
             
             % Update Counters
             app.Counter(app.Counter1,app.player1)
             app.Counter(app.Counter1_storedDeck, app.PlayedDeck_1)
             app.Counter(app.Counter2,app.player2)
             app.Counter(app.Counter2_storedDeck, app.PlayedDeck_2)
             app.Counter(app.Counter3,app.player3)
             app.Counter(app.Counter3_storedDeck, app.PlayedDeck_3)
        end

        % Button pushed function: ExitGameButton
        function ExitGame(app, event)
            delete(app)
        end

        % Button pushed function: UpdatelabelsButton
        function UpdatelabelsButtonPushed(app, event)
            % player1's PlayedDeck faceup card
            fruit1 = DetermineFruit(app, app.player1(1));
            value1 = fruitValue(app, app.player1(1));
            value = num2str(value1)
            app.Label_1.Text = strcat(fruit1, value);
            
            % player2's PlayedDeck faceup card
            fruit2 = DetermineFruit(app, app.player2(1));
            value2 = fruitValue(app, app.player2(1));
            value = num2str(value2)
            app.Label_2.Text = strcat(fruit2, value);
            
            % player3's PlayedDeck faceup card
            fruit3 = DetermineFruit(app, app.player3(1));
            value3 = fruitValue(app, app.player3(1));
            value = num2str(value3)
            app.Label_3.Text = strcat(fruit3, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CardGame23UIFigure = uifigure('Visible', 'off');
            app.CardGame23UIFigure.Name = 'Card Game 23';
            app.UIAxes_1 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.YTick = [0 0.5 1];
            app.UIAxes_1.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.CardGame23UIFigure);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.YTick = [0 0.5 1];
            app.UIAxes_3.Visible = 'off';
            app.RingButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_1.ButtonPushedFcn = createCallbackFcn(app, @ringBell_1, true);
            app.RingButton_1.Icon = 'golden_bell.jpeg';
            app.RingButton_1.Text = 'Ring';
            app.PlayCardButton_1 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_1Pushed, true);
            app.PlayCardButton_1.Text = 'Play Card';
            app.NewGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @newGame, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayCardButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_2Pushed, true);
            app.PlayCardButton_2.Text = 'Play Card';
            app.PlayCardButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.PlayCardButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayCardButton_3Pushed, true);
            app.PlayCardButton_3.Text = 'Play Card';
            app.Player1Label = uilabel(app.CardGame23UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.CardGame23UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.CardGame23UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.HalliGalli_Title = uilabel(app.CardGame23UIFigure);
            app.HalliGalli_Title.Tooltip = {'Rules: Ring the bell once 5x of one type of fruit is shown in total. Ring the bell at the wrong time and the player gives one card to the other players. The player with the most cards at the end of the game wins!'};
            app.HalliGalli_Title.Text = 'Halli Galli';
            app.RingButton_2 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ringBell_2, true);
            app.RingButton_2.Icon = 'golden_bell.jpeg';
            app.RingButton_2.Text = 'Ring';
            app.RingButton_3 = uibutton(app.CardGame23UIFigure, 'push');
            app.RingButton_3.ButtonPushedFcn = createCallbackFcn(app, @ringBell_3, true);
            app.RingButton_3.Icon = 'golden_bell.jpeg';
            app.RingButton_3.Text = 'Ring';
            app.Counter1 = uilabel(app.CardGame23UIFigure);
            app.Counter1.Text = '0';
            app.Counter2 = uilabel(app.CardGame23UIFigure);
            app.Counter2.Text = '0';
            app.Counter3 = uilabel(app.CardGame23UIFigure);
            app.Counter3.Text = '0';
            app.TotalcardsLabel_1 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_1.Text = 'Total cards:';
            app.TotalcardsLabel_2 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_2.Text = 'Total cards:';
            app.TotalcardsLabel_3 = uilabel(app.CardGame23UIFigure);
            app.TotalcardsLabel_3.Text = 'Total cards:';
            app.Counter1_storedDeck = uilabel(app.CardGame23UIFigure);
            app.Counter1_storedDeck.Text = '0';
            app.Counter2_storedDeck = uilabel(app.CardGame23UIFigure);
            app.Counter2_storedDeck.Text = '0';
            app.Counter3_storedDeck = uilabel(app.CardGame23UIFigure);
            app.Counter3_storedDeck.Text = '0';
            app.BannerLabel = uilabel(app.CardGame23UIFigure);
            app.BannerLabel.Text = {''; ''};
            app.ExitGameButton = uibutton(app.CardGame23UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGame, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.BannerLabel_2 = uilabel(app.CardGame23UIFigure);
            app.BannerLabel_2.Text = {''; ''};
            app.Label_1 = uilabel(app.CardGame23UIFigure);
            app.Label_1.Text = '-';
            app.Label_2 = uilabel(app.CardGame23UIFigure);
            app.Label_2.Text = '-';
            app.Label_3 = uilabel(app.CardGame23UIFigure);
            app.Label_3.Text = '-';
            app.UpdatelabelsButton = uibutton(app.CardGame23UIFigure, 'push');
            app.UpdatelabelsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatelabelsButtonPushed, true);
            app.UpdatelabelsButton.Text = 'Update labels';
            % Show the figure after all components are created
            app.CardGame23UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft_LeiBell

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CardGame23UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardGame23UIFigure)
        end
    end
end